classdef NewTrip < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        StartAddressEditFieldLabel      matlab.ui.control.Label
        StartAddressEditField           matlab.ui.control.EditField
        EndAddressEditFieldLabel        matlab.ui.control.Label
        EndAddressEditField             matlab.ui.control.EditField
        EnterYourStartandEndAddressLabel  matlab.ui.control.Label
        WillYouBeTakingUberorMBTALabel  matlab.ui.control.Label
        UberButton                      matlab.ui.control.Button
        MBTAButton                      matlab.ui.control.Button
        TextArea                        matlab.ui.control.TextArea
    end

    methods (Access = private)

        % Value changed function: EndAddressEditField
        function EndCallBack(app, event)
            value = app.EndAddressEditField.Value;
            [endlat endlong] = getcoordinates(value);
            save endlat endlat -ascii
            save endlong endlong -ascii
            clear
        end

        % Button pushed function: MBTAButton
        function MbtaCallback(app, event)
            load startlat
            load startlong
            load endlat
            load endlong
            mbta3(startlat, startlong, endlat, endlong)
        end

        % Value changed function: StartAddressEditField
        function StartAddCallback(app, event)
            value = app.StartAddressEditField.Value;
            [startlat startlong] = getcoordinates(value);
            save startlat startlat -ascii
            save startlong startlong -ascii
            clear
        end

        % Button pushed function: UberButton
        function UberCallback(app, event)
        UberChoice
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.StartAddressEditFieldLabel = uilabel(app.UIFigure);
            app.StartAddressEditFieldLabel.Text = 'Start Address';
            app.StartAddressEditField = uieditfield(app.UIFigure, 'text');
            app.StartAddressEditField.ValueChangedFcn = createCallbackFcn(app, @StartAddCallback, true);
            app.EndAddressEditFieldLabel = uilabel(app.UIFigure);
            app.EndAddressEditFieldLabel.Text = 'End Address';
            app.EndAddressEditField = uieditfield(app.UIFigure, 'text');
            app.EndAddressEditField.ValueChangedFcn = createCallbackFcn(app, @EndCallBack, true);
            app.EnterYourStartandEndAddressLabel = uilabel(app.UIFigure);
            app.EnterYourStartandEndAddressLabel.Text = 'Enter Your Start and End Address';
            app.WillYouBeTakingUberorMBTALabel = uilabel(app.UIFigure);
            app.WillYouBeTakingUberorMBTALabel.Text = 'Will You Be Taking Uber or MBTA?';
            app.UberButton = uibutton(app.UIFigure, 'push');
            app.UberButton.ButtonPushedFcn = createCallbackFcn(app, @UberCallback, true);
            app.UberButton.Text = 'Uber';
            app.MBTAButton = uibutton(app.UIFigure, 'push');
            app.MBTAButton.ButtonPushedFcn = createCallbackFcn(app, @MbtaCallback, true);
            app.MBTAButton.Text = 'MBTA';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'When MBTA is selected an MBTA logo will appear. if green, there are no service alerts. If red, you must check the MBTA website for more information.'};
        end
    end

    methods (Access = public)

        % Construct app
        function app = NewTrip

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end