classdef UberChoice < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        UberXButton   matlab.ui.control.Button
        WhichTypeofUberwillYouBeTakingLabel  matlab.ui.control.Label
        PoolButton    matlab.ui.control.Button
        BlackButton   matlab.ui.control.Button
        UberXLButton  matlab.ui.control.Button
    end

    methods (Access = private)

        % Button pushed function: BlackButton
        function BlackCallback(app, event)
            load startlat
            load startlong
            load endlat
            load endlong
            uber2(startlat,startlong,endlat,endlong,4)
        end

        % Button pushed function: PoolButton
        function PoolCallback(app, event)
            load startlat
            load startlong
            load endlat
            load endlong
            uber2(startlat,startlong,endlat,endlong,1)
        end

        % Button pushed function: UberXButton
        function UberXCallback(app, event)
            load startlat
            load startlong
            load endlat
            load endlong
            uber2(startlat,startlong,endlat,endlong,2)
        end

        % Button pushed function: UberXLButton
        function UberXLCallback(app, event)
            load startlat
            load startlong
            load endlat
            load endlong
            uber2(startlat,startlong,endlat,endlong,3)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UberXButton = uibutton(app.UIFigure, 'push');
            app.UberXButton.ButtonPushedFcn = createCallbackFcn(app, @UberXCallback, true);
            app.UberXButton.Text = 'UberX';
            app.WhichTypeofUberwillYouBeTakingLabel = uilabel(app.UIFigure);
            app.WhichTypeofUberwillYouBeTakingLabel.Text = 'Which Type of Uber will You Be Taking?';
            app.PoolButton = uibutton(app.UIFigure, 'push');
            app.PoolButton.ButtonPushedFcn = createCallbackFcn(app, @PoolCallback, true);
            app.PoolButton.Text = 'Pool';
            app.BlackButton = uibutton(app.UIFigure, 'push');
            app.BlackButton.ButtonPushedFcn = createCallbackFcn(app, @BlackCallback, true);
            app.BlackButton.Text = 'Black';
            app.UberXLButton = uibutton(app.UIFigure, 'push');
            app.UberXLButton.ButtonPushedFcn = createCallbackFcn(app, @UberXLCallback, true);
            app.UberXLButton.Text = 'UberXL';
        end
    end

    methods (Access = public)

        % Construct app
        function app = UberChoice

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end